classdef Application_PF_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Processingof3DPFsUIFigure      matlab.ui.Figure
        ExcludePFsshorterthanindimersEditField  matlab.ui.control.NumericEditField
        ExcludePFsshorterthanindimersEditFieldLabel  matlab.ui.control.Label
        YesNoCheckBox_4                matlab.ui.control.CheckBox
        YesNoCheckBox_3                matlab.ui.control.CheckBox
        TerminalPFcurvaturecalculationLabel  matlab.ui.control.Label
        DprotofilamentsLabel           matlab.ui.control.Label
        YesNoCheckBox_2                matlab.ui.control.CheckBox
        TechnicalviewindividualPFsbeforesmoothingLabel  matlab.ui.control.Label
        CloseallfiguresButton          matlab.ui.control.StateButton
        ProcessingofprotofilamentstracesLabel  matlab.ui.control.Label
        PixelsizenmEditField           matlab.ui.control.NumericEditField
        PixelsizenmEditFieldLabel      matlab.ui.control.Label
        FolderfortheresultsEditField   matlab.ui.control.EditField
        FolderfortheresultsEditFieldLabel  matlab.ui.control.Label
        NameofdatasetEditField         matlab.ui.control.EditField
        NameofdatasetEditFieldLabel    matlab.ui.control.Label
        PFsfilesdirectorydatEditField  matlab.ui.control.EditField
        PFsfilesdirectorydatEditFieldLabel  matlab.ui.control.Label
        StartProcessingButton          matlab.ui.control.StateButton
        YesNoCheckBox                  matlab.ui.control.CheckBox
        TechnicalmarkindividualPFsandfindtheirIDonlyin2DmodeLabel  matlab.ui.control.Label
        BrowseButton_2                 matlab.ui.control.StateButton
        BrowseButton                   matlab.ui.control.StateButton
    end

    methods (Access = public)
        function mainfunc(app)
            Main_process_PFs(app.NameofdatasetEditField.Value,...
                app.PFsfilesdirectorydatEditField.Value,...
                app.FolderfortheresultsEditField.Value,...
                app.PixelsizenmEditField.Value,...
                app.ExcludePFsshorterthanindimersEditField.Value,...
                app.YesNoCheckBox.Value, app.YesNoCheckBox_2.Value, ...
                app.YesNoCheckBox_3.Value,app.YesNoCheckBox_4.Value)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            app.PFsfilesdirectorydatEditField.Value = uigetdir();
            app.BrowseButton.Value = 0;
            figure(app.Processingof3DPFsUIFigure)
        end

        % Value changed function: BrowseButton_2
        function BrowseButton_2ValueChanged(app, event)
            app.FolderfortheresultsEditField.Value = uigetdir();
            app.BrowseButton_2.Value = 0;
            figure(app.Processingof3DPFsUIFigure)
        end

        % Value changed function: StartProcessingButton
        function StartProcessingButtonValueChanged(app, event)
            mainfunc(app)
            app.StartProcessingButton.Value = 0;
        end

        % Value changed function: CloseallfiguresButton
        function CloseallfiguresButtonValueChanged(app, event)
            close all
            app.CloseallfiguresButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Processingof3DPFsUIFigure = uifigure('Visible', 'off');
            app.Processingof3DPFsUIFigure.Name = 'Processing of 3D PFs';
            app.BrowseButton = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButton_2 = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.BrowseButton_2.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_2ValueChanged, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.TechnicalmarkindividualPFsandfindtheirIDonlyin2DmodeLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.TechnicalmarkindividualPFsandfindtheirIDonlyin2DmodeLabel.Text = {'Technical: mark individual '; 'PFs and find their ID#'; '(only in 2D mode) '};
            app.YesNoCheckBox = uicheckbox(app.Processingof3DPFsUIFigure);
            app.YesNoCheckBox.Text = 'Yes/No';
            app.StartProcessingButton = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.StartProcessingButton.ValueChangedFcn = createCallbackFcn(app, @StartProcessingButtonValueChanged, true);
            app.StartProcessingButton.Text = 'Start Processing';
            app.PFsfilesdirectorydatEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.PFsfilesdirectorydatEditFieldLabel.Text = 'PFs files directory (.dat)';
            app.PFsfilesdirectorydatEditField = uieditfield(app.Processingof3DPFsUIFigure, 'text');
            app.NameofdatasetEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.NameofdatasetEditFieldLabel.Text = 'Name of data set';
            app.NameofdatasetEditField = uieditfield(app.Processingof3DPFsUIFigure, 'text');
            app.FolderfortheresultsEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.FolderfortheresultsEditFieldLabel.Text = 'Folder for the results';
            app.FolderfortheresultsEditField = uieditfield(app.Processingof3DPFsUIFigure, 'text');
            app.PixelsizenmEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size, nm';
            app.PixelsizenmEditField = uieditfield(app.Processingof3DPFsUIFigure, 'numeric');
            app.PixelsizenmEditField.Value = 1;
            app.ProcessingofprotofilamentstracesLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.ProcessingofprotofilamentstracesLabel.Text = 'Processing of protofilaments traces';
            app.CloseallfiguresButton = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.CloseallfiguresButton.ValueChangedFcn = createCallbackFcn(app, @CloseallfiguresButtonValueChanged, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.TechnicalviewindividualPFsbeforesmoothingLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.TechnicalviewindividualPFsbeforesmoothingLabel.Text = {'Technical: view individual PFs '; 'before smoothing'};
            app.YesNoCheckBox_2 = uicheckbox(app.Processingof3DPFsUIFigure);
            app.YesNoCheckBox_2.Text = 'Yes/No';
            app.DprotofilamentsLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.DprotofilamentsLabel.Text = '3D protofilaments';
            app.TerminalPFcurvaturecalculationLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.TerminalPFcurvaturecalculationLabel.Text = {'Terminal PF curvature'; 'calculation'};
            app.YesNoCheckBox_3 = uicheckbox(app.Processingof3DPFsUIFigure);
            app.YesNoCheckBox_3.Text = 'Yes/No';
            app.YesNoCheckBox_4 = uicheckbox(app.Processingof3DPFsUIFigure);
            app.YesNoCheckBox_4.Text = 'Yes/No';
            app.ExcludePFsshorterthanindimersEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.ExcludePFsshorterthanindimersEditFieldLabel.Text = {'Exclude PFs shorter than'; '(in dimers)'};
            app.ExcludePFsshorterthanindimersEditField = uieditfield(app.Processingof3DPFsUIFigure, 'numeric');
            app.ExcludePFsshorterthanindimersEditField.Limits = [0 Inf];
            app.ExcludePFsshorterthanindimersEditField.RoundFractionalValues = 'on';
            % Show the figure after all components are created
            app.Processingof3DPFsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application_PF_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Processingof3DPFsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Processingof3DPFsUIFigure)
        end
    end
end